classdef mini_gland_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MiniGlandUIFigure               matlab.ui.Figure
        AcinusSimulationLabel           matlab.ui.control.Label
        DuctSimulationLabel             matlab.ui.control.Label
        RunSimulationButtonDuct         matlab.ui.control.Button
        RunSimulationButtonAcini        matlab.ui.control.Button
        DisplayPlotsCheckBox_2          matlab.ui.control.CheckBox
        RealCellsLabel                  matlab.ui.control.Label
        apicalENaCconductanceEditFieldLabel  matlab.ui.control.Label
        EditFieldiG_ENaC                matlab.ui.control.NumericEditField
        apicalCFTRconductanceEditFieldLabel  matlab.ui.control.Label
        EditFieldsG_CFTR                matlab.ui.control.NumericEditField
        VPLCEditFieldLabel              matlab.ui.control.Label
        VPLCEditField                   matlab.ui.control.NumericEditField
        apicalCaCCconductanceEditFieldLabel  matlab.ui.control.Label
        EditFieldiG_CaCC                matlab.ui.control.NumericEditField
        apicalMaxiKconductanceEditFieldLabel  matlab.ui.control.Label
        EditFieldiG_BK                  matlab.ui.control.NumericEditField
        basolateralKconductanceEditFieldLabel  matlab.ui.control.Label
        EditFieldiG_K_B                 matlab.ui.control.NumericEditField
        basolateralNaconductanceEditFieldLabel  matlab.ui.control.Label
        EditFieldiG_Na_B                matlab.ui.control.NumericEditField
        basolateralClconductanceEditFieldLabel  matlab.ui.control.Label
        EditFieldiG_Cl_B                matlab.ui.control.NumericEditField
        basolateralNaKATPasedensityEditFieldLabel  matlab.ui.control.Label
        EditFieldiNKA_alpha_B           matlab.ui.control.NumericEditField
        apicalNBCcotransporterdensityEditFieldLabel  matlab.ui.control.Label
        EditFieldiNBC_alpha_A           matlab.ui.control.NumericEditField
        basolateralNBCcotransporterdensityEditFieldLabel  matlab.ui.control.Label
        EditFieldiNBC_alpha_B           matlab.ui.control.NumericEditField
        basolateralanionexchangerdensityEditFieldLabel  matlab.ui.control.Label
        EditFieldiAE2_alpha_B           matlab.ui.control.NumericEditField
        tightjunctionalClconductanceEditFieldLabel  matlab.ui.control.Label
        EditFieldiG_P_Cl                matlab.ui.control.NumericEditField
        apicalwaterpermeabilityEditFieldLabel  matlab.ui.control.Label
        EditFieldiL_A                   matlab.ui.control.NumericEditField
        basolateralwaterpermeabilityEditFieldLabel  matlab.ui.control.Label
        EditFieldiL_B                   matlab.ui.control.NumericEditField
        parameterEditField_13Label      matlab.ui.control.Label
        parameterEditField_13           matlab.ui.control.NumericEditField
        parameterEditField_14Label      matlab.ui.control.Label
        parameterEditField_14           matlab.ui.control.NumericEditField
        parameterEditField_15Label      matlab.ui.control.Label
        parameterEditField_15           matlab.ui.control.NumericEditField
        parameterEditField_16Label      matlab.ui.control.Label
        parameterEditField_16           matlab.ui.control.NumericEditField
        parameterEditField_17Label      matlab.ui.control.Label
        parameterEditField_17           matlab.ui.control.NumericEditField
        parameterEditField_18Label      matlab.ui.control.Label
        parameterEditField_18           matlab.ui.control.NumericEditField
        parameterEditField_19Label      matlab.ui.control.Label
        parameterEditField_19           matlab.ui.control.NumericEditField
        SyntheticCellsLabel             matlab.ui.control.Label
        C1Button_2                      matlab.ui.control.StateButton
        C2Button                        matlab.ui.control.StateButton
        C3Button                        matlab.ui.control.StateButton
        C4Button                        matlab.ui.control.StateButton
        C5Button                        matlab.ui.control.StateButton
        C6Button                        matlab.ui.control.StateButton
        C7Button                        matlab.ui.control.StateButton
        C8Button                        matlab.ui.control.StateButton
        C9Button                        matlab.ui.control.StateButton
        C10Button                       matlab.ui.control.StateButton
        C11Button                       matlab.ui.control.StateButton
        C12Button                       matlab.ui.control.StateButton
        C13Button                       matlab.ui.control.StateButton
        C14Button                       matlab.ui.control.StateButton
        C1Button_3                      matlab.ui.control.StateButton
        C2Button_2                      matlab.ui.control.StateButton
        C3Button_2                      matlab.ui.control.StateButton
        C4Button_2                      matlab.ui.control.StateButton
        C5Button_2                      matlab.ui.control.StateButton
        C6Button_2                      matlab.ui.control.StateButton
        C7Button_2                      matlab.ui.control.StateButton
        acinussimulationfileLabel       matlab.ui.control.Label
        DuctInputDropDown               matlab.ui.control.DropDown
        EditFieldsG_ENaC                matlab.ui.control.NumericEditField
        intercalatedstriatedLabel       matlab.ui.control.Label
        EditFieldsG_BK                  matlab.ui.control.NumericEditField
        EditFieldsG_K_B                 matlab.ui.control.NumericEditField
        EditFieldsG_Na_B                matlab.ui.control.NumericEditField
        EditFieldsG_Cl_B                matlab.ui.control.NumericEditField
        EditFieldsNKA_alpha_B           matlab.ui.control.NumericEditField
        EditFieldsNBC_alpha_A           matlab.ui.control.NumericEditField
        EditFieldsNBC_alpha_B           matlab.ui.control.NumericEditField
        EditFieldsAE2_alpha_B           matlab.ui.control.NumericEditField
        EditFieldsL_A                   matlab.ui.control.NumericEditField
        EditFieldsG_P_Cl                matlab.ui.control.NumericEditField
        EditFieldsL_B                   matlab.ui.control.NumericEditField
        GeneratePlotsButton             matlab.ui.control.Button
        stimulatedfluxesCheckBox        matlab.ui.control.CheckBox
        ductatsteadystateCheckBox       matlab.ui.control.CheckBox
        ductacrossalltimeCheckBox       matlab.ui.control.CheckBox
        steadystatefluxesCheckBox       matlab.ui.control.CheckBox
        EditFieldsG_P_Na                matlab.ui.control.NumericEditField
        EditFieldsG_P_K                 matlab.ui.control.NumericEditField
        tightjunctionalNaconductanceLabel  matlab.ui.control.Label
        EditFieldiG_P_Na                matlab.ui.control.NumericEditField
        tightjunctionalKconductanceLabel  matlab.ui.control.Label
        EditFieldiG_P_K                 matlab.ui.control.NumericEditField
        apicalNHEcotransporterdensityLabel  matlab.ui.control.Label
        EditFieldNHE_alpha_A            matlab.ui.control.NumericEditField
        basolateralNHEcotransporterdensityLabel  matlab.ui.control.Label
        EditFieldNHE_alpha_B            matlab.ui.control.NumericEditField
        resultsfilelabelEditFieldLabel  matlab.ui.control.Label
        resultsfilelabelEditField       matlab.ui.control.EditField
        ductsimulationfileLabel         matlab.ui.control.Label
        PlotsInputDropDown              matlab.ui.control.DropDown
        parametersfilelabelEditFieldLabel  matlab.ui.control.Label
        parametersfilelabelEditField    matlab.ui.control.EditField
        parametersfileLabel             matlab.ui.control.Label
        MyParmsInputDropDown            matlab.ui.control.DropDown
        SaveParmsButton                 matlab.ui.control.Button
        LoadParmsButton                 matlab.ui.control.Button
        simulationtypeButtonGroup       matlab.ui.container.ButtonGroup
        ExVivoButton                    matlab.ui.control.RadioButton
        InVivoButton                    matlab.ui.control.RadioButton
        ResetDefaultParametersButton    matlab.ui.control.Button
        VisSimulationResultsButton      matlab.ui.control.Button
        acinussimulationfileLabel_2     matlab.ui.control.Label
        AcinusFileVis                   matlab.ui.control.DropDown
        ductsimulationfileLabel_2       matlab.ui.control.Label
        DuctFileVis                     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        ini_file = 'parms_ex-vivo.ini' % default simulation parameters file name
        deployed_app_path = '~/Desktop/MiniGland/' % Description
    end
    
    methods (Access = private)
        
        function set_gui_parms(app, p_i, p_s, p_c)  % NOTE: the app parameter is always prepended
            app.EditFieldiG_ENaC.Value = p_i.g_enac;
            app.EditFieldiG_CaCC.Value = p_i.g_cacc;
            app.EditFieldiG_BK.Value = p_i.g_bk;
            app.EditFieldiG_K_B.Value = p_i.g_k_b;
            app.EditFieldiG_Na_B.Value = p_i.g_na_b;
            app.EditFieldiG_Cl_B.Value = p_i.g_cl_b;
            app.EditFieldiNKA_alpha_B.Value = p_i.nka_alpha_b;
            app.EditFieldiNBC_alpha_A.Value = p_i.nbc_alpha_a;
            app.EditFieldiNBC_alpha_B.Value = p_i.nbc_alpha_b;
            app.EditFieldiAE2_alpha_B.Value = p_i.ae2_alpha_b;
            app.EditFieldiG_P_Cl.Value = p_i.g_p_cl;
            app.EditFieldiL_A.Value = p_i.l_a;
            app.EditFieldiL_B.Value = p_i.l_b;
            app.EditFieldiG_P_Na.Value = p_i.g_p_na;
            app.EditFieldiG_P_K.Value = p_i.g_p_k;

            app.EditFieldsG_ENaC.Value = p_s.g_enac;
            app.EditFieldsG_CFTR.Value = p_s.g_cftr;
            app.EditFieldsG_BK.Value = p_s.g_bk;
            app.EditFieldsG_K_B.Value = p_s.g_k_b;
            app.EditFieldsG_Na_B.Value = p_s.g_na_b;
            app.EditFieldsG_Cl_B.Value = p_s.g_cl_b;
            app.EditFieldsNKA_alpha_B.Value = p_s.nka_alpha_b;
            app.EditFieldsNBC_alpha_A.Value = p_s.nbc_alpha_a;
            app.EditFieldsNBC_alpha_B.Value = p_s.nbc_alpha_b;
            app.EditFieldsAE2_alpha_B.Value = p_s.ae2_alpha_b;
            app.EditFieldsG_P_Cl.Value = p_s.g_p_cl;
            app.EditFieldsL_A.Value = p_s.l_a;
            app.EditFieldsL_B.Value = p_s.l_b;
            app.EditFieldsG_P_Na.Value = p_s.g_p_na;
            app.EditFieldsG_P_K.Value = p_s.g_p_k;

            app.EditFieldNHE_alpha_A.Value = p_c.nhe_alpha_a;
            app.EditFieldNHE_alpha_B.Value = p_c.nhe_alpha_b;
        end

        function set_gui_parms_ini(app)
            cd('duct');
            INI = ini2struct(app.ini_file);
            cd('..');
            app.set_gui_parms(INI.intercalated, INI.striated, INI.duct_common);
        end

        function parms = get_gui_parms(app)  % NOTE: the app parameter is always prepended
            p_i.g_enac = app.EditFieldiG_ENaC.Value;
            p_i.g_cacc = app.EditFieldiG_CaCC.Value;
            p_i.g_bk = app.EditFieldiG_BK.Value;
            p_i.g_k_b = app.EditFieldiG_K_B.Value;
            p_i.g_na_b = app.EditFieldiG_Na_B.Value;
            p_i.g_cl_b = app.EditFieldiG_Cl_B.Value;
            p_i.nka_alpha_b = app.EditFieldiNKA_alpha_B.Value;
            p_i.nbc_alpha_a = app.EditFieldiNBC_alpha_A.Value;
            p_i.nbc_alpha_b = app.EditFieldiNBC_alpha_B.Value;
            p_i.ae2_alpha_b = app.EditFieldiAE2_alpha_B.Value;
            p_i.g_p_cl = app.EditFieldiG_P_Cl.Value;
            p_i.l_a = app.EditFieldiL_A.Value;
            p_i.l_b = app.EditFieldiL_B.Value;
            p_i.g_p_na = app.EditFieldiG_P_Na.Value;
            p_i.g_p_k = app.EditFieldiG_P_K.Value;
                        
            p_s.g_enac = app.EditFieldsG_ENaC.Value;
            p_s.g_cftr = app.EditFieldsG_CFTR.Value;
            p_s.g_bk = app.EditFieldsG_BK.Value;
            p_s.g_k_b = app.EditFieldsG_K_B.Value;
            p_s.g_na_b = app.EditFieldsG_Na_B.Value;
            p_s.g_cl_b = app.EditFieldsG_Cl_B.Value;
            p_s.nka_alpha_b = app.EditFieldsNKA_alpha_B.Value;
            p_s.nbc_alpha_a = app.EditFieldsNBC_alpha_A.Value;
            p_s.nbc_alpha_b = app.EditFieldsNBC_alpha_B.Value;
            p_s.ae2_alpha_b = app.EditFieldsAE2_alpha_B.Value;
            p_s.g_p_cl = app.EditFieldsG_P_Cl.Value;
            p_s.l_a = app.EditFieldsL_A.Value;
            p_s.l_b = app.EditFieldsL_B.Value;
            p_s.g_p_na = app.EditFieldsG_P_Na.Value;
            p_s.g_p_k = app.EditFieldsG_P_K.Value;

            p_c.nhe_alpha_a = app.EditFieldNHE_alpha_A.Value;
            p_c.nhe_alpha_b = app.EditFieldNHE_alpha_B.Value;
            
            parms.p_i = p_i;
            parms.p_s = p_s;
            parms.p_c = p_c;
        end
        function set_plots_input(app)  % NOTE: the app parameter is always prepended
            files = {dir("duct/result_*.mat").name}; % duct result files
            if ~isempty(files) 
                app.PlotsInputDropDown.Items = files;
                app.PlotsInputDropDown.Value = files(1);
            end
        end
        function set_parm_files(app)  % NOTE: the app parameter is always prepended
            files = {dir("duct/myparms_*.mat").name}; % my parameters input files
            if ~isempty(files) 
                app.MyParmsInputDropDown.Items = ["- none -" files];
                app.MyParmsInputDropDown.Value = "- none -";
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Launching MiniGland app...');
            files = {dir("acinus/result_*.mat").name}; % acinus result files
            if ~isempty(files) 
                app.DuctInputDropDown.Items = files;
                app.DuctInputDropDown.Value = files(1);
            end
            app.set_gui_parms_ini();          
            app.set_parm_files();
            app.set_plots_input();
        end

        % Button pushed function: RunSimulationButtonAcini
        function RunSimulationButtonAciniPushed(app, event)
            disp('Running acinus simulation...');
            run_acinus();
            disp('Acinus simulation complete');
        end

        % Button pushed function: RunSimulationButtonDuct
        function RunSimulationButtonDuctPushed(app, event)
            if strcmp(get(app.DuctInputDropDown,'Value'), '- none -')
                return;
            end
            disp('Running duct simulation...');
            t = tic;
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Running duct simulation','Indeterminate','on');
            run_duct(app.ini_file, app.get_gui_parms_ini(), ...
                get(app.DuctInputDropDown,'Value'), get(app.resultsfilelabelEditField,'Value'));
            app.set_plots_input();
            close(d)
            disp('Duct simulation complete.');
            uialert(app.MiniGlandUIFigure,{'Elapsed time: ';toc2hms(toc(t));pwd},'Duct simulation complete','Icon','success');
        end

        % Callback function
        function ExVivoButtonPushed(app, event)
            disp('Loading ex-vivo parms.');
            app.ini_file = 'parms_ex-vivo.ini';
            app.set_gui_parms_ini();
        end

        % Callback function
        function InVivoButtonPushed(app, event)
            disp('Loading in-vivo parms.');
            app.ini_file = 'parms_in-vivo.ini';
            app.set_gui_parms_ini();
        end

        % Close request function: MiniGlandUIFigure
        function MiniGlandUIFigureCloseRequest(app, event)
            delete(app)
            disp('Exiting MiniGland app');
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            if strcmp(get(app.PlotsInputDropDown,'Value'), '- none -')
                return;
            end
            disp('Generating duct plot(s)...');
            p.ductatfixedtime = app.ductatsteadystateCheckBox.Value;
            p.ductacrossalltime = app.ductacrossalltimeCheckBox.Value;
            p.steadystatefluxes = app.steadystatefluxesCheckBox.Value;
            p.stimulatedfluxes = app.stimulatedfluxesCheckBox.Value;
            plot_duct(app.PlotsInputDropDown.Value, p);
            disp('Plot(s) completed.');
        end

        % Button pushed function: SaveParmsButton
        function SaveParmsButtonPushed(app, event)
            parms = app.get_gui_parms;
            cd('duct');
            save(strcat("myparms_",app.parametersfilelabelEditField.Value,".mat"),"parms");
            cd('..');
            app.set_parm_files();
        end

        % Button pushed function: LoadParmsButton
        function LoadParmsButtonPushed(app, event)
            if strcmp(get(app.MyParmsInputDropDown,'Value'), '- none -')
                return;
            end
            load(strcat("duct/", get(app.MyParmsInputDropDown,'Value')), "parms");
            app.set_gui_parms(parms.p_i, parms.p_s, parms.p_c);
        end

        % Selection changed function: simulationtypeButtonGroup
        function simulationtypeButtonGroupSelectionChanged(app, event)
            if app.ExVivoButton.Value
                app.ini_file = 'parms_ex-vivo.ini';
           else
                app.ini_file = 'parms_in-vivo.ini';
            end          
            app.set_gui_parms_ini();
            app.set_parm_files();
        end

        % Button pushed function: ResetDefaultParametersButton
        function ResetDefaultParametersButtonPushed(app, event)
            app.set_gui_parms_ini();
            app.set_parm_files();            
        end

        % Button pushed function: VisSimulationResultsButton
        function VisSimulationResultsButtonPushed(app, event)
            cd("../MiniGlandVis");
            !mini_gland.app/Contents/MacOS/MiniGland &
            cd("../MiniGlandSim");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MiniGlandUIFigure = uifigure('Visible', 'off');
            app.MiniGlandUIFigure.Name = 'MiniGland';
            app.MiniGlandUIFigure.CloseRequestFcn = createCallbackFcn(app, @MiniGlandUIFigureCloseRequest, true);
            app.AcinusSimulationLabel = uilabel(app.MiniGlandUIFigure);
            app.AcinusSimulationLabel.Text = 'Acinus Simulation';
            app.DuctSimulationLabel = uilabel(app.MiniGlandUIFigure);
            app.DuctSimulationLabel.Text = 'Duct Simulation';
            app.RunSimulationButtonDuct = uibutton(app.MiniGlandUIFigure, 'push');
            app.RunSimulationButtonDuct.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonDuctPushed, true);
            app.RunSimulationButtonDuct.Text = 'Run Simulation';
            app.RunSimulationButtonAcini = uibutton(app.MiniGlandUIFigure, 'push');
            app.RunSimulationButtonAcini.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonAciniPushed, true);
            app.RunSimulationButtonAcini.Text = 'Run Simulation';
            app.DisplayPlotsCheckBox_2 = uicheckbox(app.MiniGlandUIFigure);
            app.DisplayPlotsCheckBox_2.Text = 'Display Plots';
            app.RealCellsLabel = uilabel(app.MiniGlandUIFigure);
            app.RealCellsLabel.Text = 'Real Cells';
            app.apicalENaCconductanceEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.apicalENaCconductanceEditFieldLabel.Text = 'apical ENaC conductance';
            app.EditFieldiG_ENaC = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_ENaC.Limits = [0 Inf];
            app.EditFieldiG_ENaC.ValueDisplayFormat = '%11.6g';
            app.apicalCFTRconductanceEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.apicalCFTRconductanceEditFieldLabel.Text = 'apical CFTR conductance';
            app.EditFieldsG_CFTR = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_CFTR.Limits = [0 Inf];
            app.EditFieldsG_CFTR.ValueDisplayFormat = '%11.6g';
            app.VPLCEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.VPLCEditFieldLabel.Text = 'VPLC';
            app.VPLCEditField = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.apicalCaCCconductanceEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.apicalCaCCconductanceEditFieldLabel.Text = 'apical CaCC conductance';
            app.EditFieldiG_CaCC = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_CaCC.Limits = [0 Inf];
            app.EditFieldiG_CaCC.ValueDisplayFormat = '%11.6g';
            app.apicalMaxiKconductanceEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.apicalMaxiKconductanceEditFieldLabel.Text = 'apical Maxi-K conductance';
            app.EditFieldiG_BK = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_BK.Limits = [0 Inf];
            app.EditFieldiG_BK.ValueDisplayFormat = '%11.6g';
            app.basolateralKconductanceEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralKconductanceEditFieldLabel.Text = 'basolateral K+ conductance';
            app.EditFieldiG_K_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_K_B.Limits = [0 Inf];
            app.EditFieldiG_K_B.ValueDisplayFormat = '%11.6g';
            app.basolateralNaconductanceEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralNaconductanceEditFieldLabel.Text = 'basolateral Na+ conductance';
            app.EditFieldiG_Na_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_Na_B.Limits = [0 Inf];
            app.EditFieldiG_Na_B.ValueDisplayFormat = '%11.6g';
            app.basolateralClconductanceEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralClconductanceEditFieldLabel.Text = 'basolateral Cl- conductance';
            app.EditFieldiG_Cl_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_Cl_B.Limits = [0 Inf];
            app.EditFieldiG_Cl_B.ValueDisplayFormat = '%11.6g';
            app.basolateralNaKATPasedensityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralNaKATPasedensityEditFieldLabel.Text = 'basolateral NaK ATPase density';
            app.EditFieldiNKA_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiNKA_alpha_B.Limits = [0 Inf];
            app.EditFieldiNKA_alpha_B.ValueDisplayFormat = '%11.6g';
            app.apicalNBCcotransporterdensityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.apicalNBCcotransporterdensityEditFieldLabel.Text = 'apical NBC cotransporter density';
            app.EditFieldiNBC_alpha_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiNBC_alpha_A.Limits = [0 Inf];
            app.EditFieldiNBC_alpha_A.ValueDisplayFormat = '%11.6g';
            app.basolateralNBCcotransporterdensityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralNBCcotransporterdensityEditFieldLabel.Text = 'basolateral NBC cotransporter density';
            app.EditFieldiNBC_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiNBC_alpha_B.Limits = [0 Inf];
            app.EditFieldiNBC_alpha_B.ValueDisplayFormat = '%11.6g';
            app.basolateralanionexchangerdensityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralanionexchangerdensityEditFieldLabel.Text = 'basolateral anion exchanger density';
            app.EditFieldiAE2_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiAE2_alpha_B.Limits = [0 Inf];
            app.EditFieldiAE2_alpha_B.ValueDisplayFormat = '%11.6g';
            app.tightjunctionalClconductanceEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.tightjunctionalClconductanceEditFieldLabel.Text = 'tight-junctional Cl- conductance';
            app.EditFieldiG_P_Cl = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_P_Cl.Limits = [0 Inf];
            app.EditFieldiG_P_Cl.ValueDisplayFormat = '%11.6g';
            app.apicalwaterpermeabilityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.apicalwaterpermeabilityEditFieldLabel.Text = 'apical water permeability';
            app.EditFieldiL_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiL_A.Limits = [0 Inf];
            app.EditFieldiL_A.ValueDisplayFormat = '%11.6g';
            app.basolateralwaterpermeabilityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralwaterpermeabilityEditFieldLabel.Text = 'basolateral water permeability';
            app.EditFieldiL_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiL_B.Limits = [0 Inf];
            app.EditFieldiL_B.ValueDisplayFormat = '%11.6g';
            app.parameterEditField_13Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_13Label.Text = 'parameter';
            app.parameterEditField_13 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_14Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_14Label.Text = 'parameter';
            app.parameterEditField_14 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_15Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_15Label.Text = 'parameter';
            app.parameterEditField_15 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_16Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_16Label.Text = 'parameter';
            app.parameterEditField_16 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_17Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_17Label.Text = 'parameter';
            app.parameterEditField_17 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_18Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_18Label.Text = 'parameter';
            app.parameterEditField_18 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_19Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_19Label.Text = 'parameter';
            app.parameterEditField_19 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.SyntheticCellsLabel = uilabel(app.MiniGlandUIFigure);
            app.SyntheticCellsLabel.Text = 'Synthetic Cells';
            app.C1Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C1Button_2.Text = 'C1';
            app.C2Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C2Button.Text = 'C2';
            app.C3Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C3Button.Text = 'C3';
            app.C4Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C4Button.Text = 'C4';
            app.C5Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C5Button.Text = 'C5';
            app.C6Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C6Button.Text = 'C6';
            app.C7Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C7Button.Text = 'C7';
            app.C8Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C8Button.Text = 'C8';
            app.C9Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C9Button.Text = 'C9';
            app.C10Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C10Button.Text = 'C10';
            app.C11Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C11Button.Text = 'C11';
            app.C12Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C12Button.Text = 'C12';
            app.C13Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C13Button.Text = 'C13';
            app.C14Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C14Button.Text = 'C14';
            app.C1Button_3 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C1Button_3.Text = 'C1';
            app.C2Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C2Button_2.Text = 'C2';
            app.C3Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C3Button_2.Text = 'C3';
            app.C4Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C4Button_2.Text = 'C4';
            app.C5Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C5Button_2.Text = 'C5';
            app.C6Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C6Button_2.Text = 'C6';
            app.C7Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C7Button_2.Text = 'C7';
            app.acinussimulationfileLabel = uilabel(app.MiniGlandUIFigure);
            app.acinussimulationfileLabel.Text = 'acinus simulation file';
            app.DuctInputDropDown = uidropdown(app.MiniGlandUIFigure);
            app.DuctInputDropDown.Items = {'- none -', ''};
            app.DuctInputDropDown.Value = '- none -';
            app.EditFieldsG_ENaC = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_ENaC.Limits = [0 Inf];
            app.EditFieldsG_ENaC.ValueDisplayFormat = '%11.6g';
            app.intercalatedstriatedLabel = uilabel(app.MiniGlandUIFigure);
            app.intercalatedstriatedLabel.Text = 'intercalated                  striated';
            app.EditFieldsG_BK = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_BK.Limits = [0 Inf];
            app.EditFieldsG_BK.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_K_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_K_B.Limits = [0 Inf];
            app.EditFieldsG_K_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_Na_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_Na_B.Limits = [0 Inf];
            app.EditFieldsG_Na_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_Cl_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_Cl_B.Limits = [0 Inf];
            app.EditFieldsG_Cl_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNKA_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsNKA_alpha_B.Limits = [0 Inf];
            app.EditFieldsNKA_alpha_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNBC_alpha_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsNBC_alpha_A.Limits = [0 Inf];
            app.EditFieldsNBC_alpha_A.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNBC_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsNBC_alpha_B.Limits = [0 Inf];
            app.EditFieldsNBC_alpha_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsAE2_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsAE2_alpha_B.Limits = [0 Inf];
            app.EditFieldsAE2_alpha_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsL_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsL_A.Limits = [0 Inf];
            app.EditFieldsL_A.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_P_Cl = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_P_Cl.Limits = [0 Inf];
            app.EditFieldsG_P_Cl.ValueDisplayFormat = '%11.6g';
            app.EditFieldsL_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsL_B.Limits = [0 Inf];
            app.EditFieldsL_B.ValueDisplayFormat = '%11.6g';
            app.GeneratePlotsButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Text = 'Generate Plots';
            app.stimulatedfluxesCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.stimulatedfluxesCheckBox.Text = 'stimulated fluxes';
            app.ductatsteadystateCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.ductatsteadystateCheckBox.Text = 'duct at steady state';
            app.ductatsteadystateCheckBox.Value = true;
            app.ductacrossalltimeCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.ductacrossalltimeCheckBox.Text = 'duct across all time';
            app.ductacrossalltimeCheckBox.Value = true;
            app.steadystatefluxesCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.steadystatefluxesCheckBox.Text = 'steady state fluxes';
            app.EditFieldsG_P_Na = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_P_Na.Limits = [0 Inf];
            app.EditFieldsG_P_Na.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_P_K = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_P_K.Limits = [0 Inf];
            app.EditFieldsG_P_K.ValueDisplayFormat = '%11.6g';
            app.tightjunctionalNaconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.tightjunctionalNaconductanceLabel.Text = 'tight-junctional Na+ conductance';
            app.EditFieldiG_P_Na = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_P_Na.Limits = [0 Inf];
            app.EditFieldiG_P_Na.ValueDisplayFormat = '%11.6g';
            app.tightjunctionalKconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.tightjunctionalKconductanceLabel.Text = 'tight-junctional K+ conductance';
            app.EditFieldiG_P_K = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_P_K.Limits = [0 Inf];
            app.EditFieldiG_P_K.ValueDisplayFormat = '%11.6g';
            app.apicalNHEcotransporterdensityLabel = uilabel(app.MiniGlandUIFigure);
            app.apicalNHEcotransporterdensityLabel.Text = 'apical NHE cotransporter density';
            app.EditFieldNHE_alpha_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldNHE_alpha_A.Limits = [0 Inf];
            app.EditFieldNHE_alpha_A.ValueDisplayFormat = '%11.6g';
            app.basolateralNHEcotransporterdensityLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralNHEcotransporterdensityLabel.Text = 'basolateral NHE cotransporter density';
            app.EditFieldNHE_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldNHE_alpha_B.Limits = [0 Inf];
            app.EditFieldNHE_alpha_B.ValueDisplayFormat = '%11.6g';
            app.resultsfilelabelEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.resultsfilelabelEditFieldLabel.Text = 'results file label';
            app.resultsfilelabelEditField = uieditfield(app.MiniGlandUIFigure, 'text');
            app.ductsimulationfileLabel = uilabel(app.MiniGlandUIFigure);
            app.ductsimulationfileLabel.Text = 'duct simulation file';
            app.PlotsInputDropDown = uidropdown(app.MiniGlandUIFigure);
            app.PlotsInputDropDown.Items = {'- none -', ''};
            app.PlotsInputDropDown.Value = '- none -';
            app.parametersfilelabelEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.parametersfilelabelEditFieldLabel.Text = 'parameters file label';
            app.parametersfilelabelEditField = uieditfield(app.MiniGlandUIFigure, 'text');
            app.parametersfileLabel = uilabel(app.MiniGlandUIFigure);
            app.parametersfileLabel.Text = 'parameters file';
            app.MyParmsInputDropDown = uidropdown(app.MiniGlandUIFigure);
            app.MyParmsInputDropDown.Items = {'- none -', ''};
            app.MyParmsInputDropDown.Value = '- none -';
            app.SaveParmsButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.SaveParmsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParmsButtonPushed, true);
            app.SaveParmsButton.Text = 'Save';
            app.LoadParmsButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.LoadParmsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParmsButtonPushed, true);
            app.LoadParmsButton.Text = 'Load';
            app.simulationtypeButtonGroup = uibuttongroup(app.MiniGlandUIFigure);
            app.simulationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @simulationtypeButtonGroupSelectionChanged, true);
            app.simulationtypeButtonGroup.Title = 'simulation type';
            app.ExVivoButton = uiradiobutton(app.simulationtypeButtonGroup);
            app.ExVivoButton.Text = 'Ex-Vivo';
            app.ExVivoButton.Value = true;
            app.InVivoButton = uiradiobutton(app.simulationtypeButtonGroup);
            app.InVivoButton.Text = 'In-Vivo';
            app.ResetDefaultParametersButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.ResetDefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultParametersButtonPushed, true);
            app.ResetDefaultParametersButton.Text = 'Reset Default Parameters';
            app.VisSimulationResultsButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.VisSimulationResultsButton.ButtonPushedFcn = createCallbackFcn(app, @VisSimulationResultsButtonPushed, true);
            app.VisSimulationResultsButton.Text = '3D Visualisation of Simulation Results';
            app.acinussimulationfileLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.acinussimulationfileLabel_2.Text = 'acinus simulation file';
            app.AcinusFileVis = uidropdown(app.MiniGlandUIFigure);
            app.AcinusFileVis.Items = {'- none -', ''};
            app.AcinusFileVis.Value = '- none -';
            app.ductsimulationfileLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.ductsimulationfileLabel_2.Text = 'duct simulation file';
            app.DuctFileVis = uidropdown(app.MiniGlandUIFigure);
            app.DuctFileVis.Items = {'- none -', ''};
            app.DuctFileVis.Value = '- none -';
            % Show the figure after all components are created
            app.MiniGlandUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mini_gland_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MiniGlandUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MiniGlandUIFigure)
        end
    end
end